classdef D12_Controller < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        PlotButton               matlab.ui.control.Button
        ClearButton              matlab.ui.control.Button
        TriangulateonGridButton  matlab.ui.control.Button
        OccupancyEditField       matlab.ui.control.NumericEditField
        OccupancyLabel           matlab.ui.control.Label
        ScansEditField           matlab.ui.control.NumericEditField
        ScansEditFieldLabel      matlab.ui.control.Label
        StartLidarButton         matlab.ui.control.Button
        CloseButton              matlab.ui.control.Button
        ConnectSwitch            matlab.ui.control.Switch
        ConnectedLamp            matlab.ui.control.Lamp
        ManualControlPanel       matlab.ui.container.Panel
        Forward                  matlab.ui.control.Button
        Left                     matlab.ui.control.Button
        Right                    matlab.ui.control.Button
        Back                     matlab.ui.control.Button
        RightPanel               matlab.ui.container.Panel
        GridLayout2              matlab.ui.container.GridLayout
        UiOccupancy              matlab.ui.control.UIAxes
        UiImmediatePose          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        CbMask
        Poses % list of existing robot poses
        Maps  %List of existing SLAM Maps
        Simulator
        
        ClientId
        CurrentStatus
        CurrentLocation =[2,5]  %fix a location to start estimates from

        Handles
        IP
        Port

        IsLidarRunning = false
        LidarSlam % reference to Simultaneous Location And Mapping
        LidarScansMax = 2000
        LidarScans = {} % reference to Simultaneous Location And Mapping
        LidarScansCount = 0
        LidarMap
        LidarTimer
        LidarRange = 8
        LidarResolution = 20
        MappingPoints

        IsOccupancyRunning = false
        OccupancyGrid
        OccupancyTimer
        OccupancyMagFactor = 1
        
    end
    
    methods (Access = public)

        function PlotPose(app,~)
            plot(app.UiImmediatePose,app.MappingPoints(1,:)', app.MappingPoints(2,:)', 'b.'); 
        end

        function PlotTriangulation(app,~)
            imagesc(app.UiOccupancy, app.OccupancyGrid);
            colormap(app.UiOccupancy,"gray");
            axis(app.UiOccupancy, 'tight');
        end

        function PlotLidar(app, ~)

            % Reset the SLAM object
            app.LidarSlam = lidarSLAM(app.LidarResolution, app.LidarRange);
            app.LidarScans = app.LidarScans(1:end);  % Ensure this slicing is appropriate
            
            plotHandle = figure('Name','Plotting' ,'Position',app.UIFigure.Position, 'ToolBar','none','MenuBar','none');
            for i = 1:numel(app.LidarScans)
                addScan(app.LidarSlam, app.LidarScans{i});
                if rem(i,10) == 0
                    show(app.LidarSlam);
                    figure(plotHandle);
                end
                plotHandle.Name = "Added " + num2str(i) + " Scans of " + num2str(numel(app.LidarScans)) + " to plot...";
                drawnow;
            end
            %close(plotHandle);

            [scansSLAM, poses] = scansAndPoses(app.LidarSlam);
            occMapHandle = figure('Name','Occupancy Grid','Position',app.UIFigure.Position, 'ToolBar','none','MenuBar','none');
            occMap = buildMap(scansSLAM, poses, app.LidarResolution, app.LidarRange);
            show(occMap);
            figure(occMapHandle);

            % Step 1: Get the full occupancy matrix from the grid
            occupancyMatrix = getOccupancy(occMap);
            
            % Step 2: Analyze the occupancy matrix
            
            % Calculate the percentage of occupied and free cells
            occupiedThreshold = occMap.OccupiedThreshold;  % Default is usually 0.65
            freeThreshold = occMap.FreeThreshold;          % Default is usually 0.196
            
            % Cells with occupancy probability higher than the occupiedThreshold are considered occupied
            occupiedCells = occupancyMatrix > occupiedThreshold;
            
            % Cells with occupancy probability lower than the freeThreshold are considered free
            freeCells = occupancyMatrix < freeThreshold;
            
            % Calculate the percentages
            totalCells = numel(occupancyMatrix);
            OccupiedCells = sum(occupiedCells(:))
            FreeCells = sum(freeCells(:))
            
            % Output the results
            app.OccupancyEditField.Value = 100 * (OccupiedCells / (OccupiedCells + FreeCells));


        end


        function ClearCallBackMask(app,~)
            app.CbMask = 0;
        end

        function NotifyActivationStatus(app,~)
            app.CbMask = 1;
            actionContols = [app.ManualControlPanel, app.Left, app.Right, app.Back, app.Forward, app.StartLidarButton, app.PlotButton, app.ClearButton, app.TriangulateonGridButton];

            for k=1:numel(actionContols)
                actionContols(k).Enable = app.CurrentStatus.Connected;
            end

            if(app.CurrentStatus.Connected)
                app.ConnectSwitch.Value="On";
                app.ConnectedLamp.Color=[0.00,1.00,0.00];
            else
                app.ConnectSwitch.Value="Off";
                app.ConnectedLamp.Color=[1.00,0.00,0.00];
            end
            ClearCallBackMask(app);
        end
        
        function NotifyLidarUpdate(app,~)
            app.ScansEditField.Value = app.LidarScansCount;
            if(app.IsLidarRunning)
                app.StartLidarButton.Text = "Stop Lidar";
                app.ScansEditField.Value = app.LidarScansCount;
                PlotPose(app);
            else
                app.StartLidarButton.Text = "Start Lidar";
            end
        end

        function NotifyOccupancyUpdate(app,~)
             if(app.IsOccupancyRunning)
                app.TriangulateonGridButton.Text = "Stop Triangulation";
                PlotTriangulation(app,[])
            else
                app.TriangulateonGridButton.Text = "Triangulate on Grid";
            end
        end
    end
        

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ConnectSwitch
        function ConnectSwitchValueChanged(app, event)
            

            if(bitand(app.CbMask,1))
                return;
            end
            app.IP = '127.0.0.1';
            app.Port = 23333;
            value = app.ConnectSwitch.Value;
            if(value=="Off")
                onDisconnect(app,@NotifyActivationStatus)
            else
               onConnect(app,@NotifyActivationStatus);
            end

        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            stop(app.LidarTimer);
            delete(app.LidarTimer);
            stop(app.OccupancyTimer);
            delete(app.OccupancyTimer);
            onClose(app,[]);
            delete(app);
        end

        % Button pushed function: Left
        function LeftButtonPushed(app, event)
            onMove(app,DirectionEnum.Left);
        end

        % Button pushed function: Right
        function RightButtonPushed(app, event)
            onMove(app,DirectionEnum.Right);
        end

        % Button pushed function: Forward
        function ForwardButtonPushed(app, event)
            onMove(app,DirectionEnum.Forward);
        end

        % Button pushed function: Back
        function BackButtonPushed(app, event)
            onMove(app,DirectionEnum.Back);
        end

        % Button pushed function: StartLidarButton
        function StartLidarButtonPushed(app, event)
            onLidar(app,@NotifyLidarUpdate);
        end

        % Button pushed function: TriangulateonGridButton
        function TriangulateonGridButtonPushed(app, event)
            onTrackOccupancy(app,@NotifyOccupancyUpdate);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.OccupancyGrid = zeros(10,10);
            app.PlotTriangulation(app)
            app.LidarScans = {};
            app.LidarScansCount = 0;
            app.NotifyLidarUpdate(app);

        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            PlotLidar(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {367, 367};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {157, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ManualControlPanel = uipanel(app.LeftPanel);
            app.ManualControlPanel.Enable = 'off';
            app.ManualControlPanel.Title = 'Manual Control';
            app.Back = uibutton(app.ManualControlPanel, 'push');
            app.Back.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.Back.Enable = 'off';
            app.Back.Text = 'B';
            app.Right = uibutton(app.ManualControlPanel, 'push');
            app.Right.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.Right.Enable = 'off';
            app.Right.Text = 'R';
            app.Left = uibutton(app.ManualControlPanel, 'push');
            app.Left.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.Left.Enable = 'off';
            app.Left.Text = 'L';
            app.Forward = uibutton(app.ManualControlPanel, 'push');
            app.Forward.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.Forward.Enable = 'off';
            app.Forward.Text = 'F';
            app.ConnectedLamp = uilamp(app.LeftPanel);
            app.ConnectSwitch = uiswitch(app.LeftPanel, 'slider');
            app.ConnectSwitch.ValueChangedFcn = createCallbackFcn(app, @ConnectSwitchValueChanged, true);
            app.CloseButton = uibutton(app.LeftPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.StartLidarButton = uibutton(app.LeftPanel, 'push');
            app.StartLidarButton.ButtonPushedFcn = createCallbackFcn(app, @StartLidarButtonPushed, true);
            app.StartLidarButton.Text = 'Start Lidar';
            app.ScansEditFieldLabel = uilabel(app.LeftPanel);
            app.ScansEditFieldLabel.Text = 'Scans';
            app.ScansEditField = uieditfield(app.LeftPanel, 'numeric');
            app.OccupancyLabel = uilabel(app.LeftPanel);
            app.OccupancyLabel.Text = 'Occupancy';
            app.OccupancyEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TriangulateonGridButton = uibutton(app.LeftPanel, 'push');
            app.TriangulateonGridButton.ButtonPushedFcn = createCallbackFcn(app, @TriangulateonGridButtonPushed, true);
            app.TriangulateonGridButton.Text = 'Triangulate on Grid';
            app.ClearButton = uibutton(app.LeftPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.PlotButton = uibutton(app.LeftPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UiImmediatePose = uiaxes(app.GridLayout2);
            title(app.UiImmediatePose, 'Pose View')
            subtitle(app.UiImmediatePose, 'Realtime Lidar Perspective')
            app.UiImmediatePose.XTick = [];
            app.UiImmediatePose.YTick = [];
            app.UiOccupancy = uiaxes(app.GridLayout2);
            title(app.UiOccupancy, 'Triangulation View')
            subtitle(app.UiOccupancy, 'Robot Grid Sector')
            app.UiOccupancy.XTick = [];
            app.UiOccupancy.XTickLabel = {'5'};
            app.UiOccupancy.YTick = [];
            app.UiOccupancy.YTickLabel = {'10'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = D12_Controller

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end